classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        SelectMethodDropDownLabel    matlab.ui.control.Label
        SelectMethodDropDown         matlab.ui.control.DropDown
        FunctionIsEditFieldLabel     matlab.ui.control.Label
        FunctionIsEditField          matlab.ui.control.EditField
        X0EditFieldLabel             matlab.ui.control.Label
        X0EditField                  matlab.ui.control.NumericEditField
        X1EditFieldLabel             matlab.ui.control.Label
        X1EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        maxErrorEditField            matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        ModeSwitchLabel              matlab.ui.control.Label
        ModeSwitch                   matlab.ui.control.Switch
        CalculateButton              matlab.ui.control.Button
        selectfileButton             matlab.ui.control.Button
        IntervalEditFieldLabel       matlab.ui.control.Label
        IntervalEditField            matlab.ui.control.NumericEditField
        generalRootsLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UITable2                     matlab.ui.control.Table
        fileButton                   matlab.ui.control.Button
    end

    properties (Access = public)
        currentIteration = 0;
        value = 0;
        xr_old = 0;
        oldx2 = 0;
        oldX = 0 ;
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            zoom(app.UIAxes,'on');
            f = str2func(['@(x)' app.FunctionIsEditField.Value]);
            x0 = app.X0EditField.Value;
            x1 = app.X1EditField.Value;
            maxError = app.maxErrorEditField.Value;
            maxItr = app.MaxIterationsEditField.Value;
            mode = app.ModeSwitch.Value;
            
            switch (app.SelectMethodDropDown.Value)
               case 'Bisection'
                    [itr, x_l, x_u,x_old] = Bisection(f,app.currentIteration, x1, x0,maxError,maxItr,mode, app.UITable2,app.oldX)
                    app.X0EditField.Value = x_l;
                    app.X1EditField.Value = x_u;
                    app.currentIteration = itr;
                    app.oldX = x_old;
                    cla(app.UIAxes);
                    fplot(app.UIAxes,f);
                    hold(app.UIAxes);
                    fplot (app.UIAxes, x_l, @(x_l) x_l * 200 , '-g'); 
                    fplot (app.UIAxes, x_u, @(x_u) x_u * 200 , '-r');
                    hold(app.UIAxes);
            
                case 'False-Position'
                    [itr, x_l, x_u,x_old] = falsePosition(f,app.currentIteration, x1, x0,maxError,maxItr,mode, app.UITable2,app.oldx2)
                    app.X0EditField.Value = x_l;
                    app.X1EditField.Value = x_u;
                    app.currentIteration = itr;
                    app.oldx2 = x_old;
                    cla(app.UIAxes);
                    fplot(app.UIAxes,f);
                    hold(app.UIAxes);
                    plot (app.UIAxes, [x_l x_u], [subs(f,x_l) subs(f,x_u)]);
                    hold(app.UIAxes);
                    
                case 'Fixed Point'
                    [root, i] = FPI(f, app.currentIteration, x0, maxError, maxItr, mode, app.UITable2);
                    app.X0EditField.Value = root;
                    app.currentIteration = i;
                    
                    cla(app.UIAxes);
                    syms g(x);
                    g(x) = f + x;
                    fplot(app.UIAxes,g);
                    hold(app.UIAxes);
                    fplot (app.UIAxes,@(x)x);
                    hold(app.UIAxes);
                    
                case 'Newton-Raphson'
                    [root, i, dif] = NewtonRaphson(f,app.currentIteration, x0,maxItr,maxError,mode, app.UITable2);
                    app.X0EditField.Value = root;
                    app.currentIteration = i;
                    
                    cla(app.UIAxes);
                    fplot(app.UIAxes,f);
                    hold(app.UIAxes);
                    fplot (app.UIAxes,dif, '-r');
                    hold(app.UIAxes);
                            
                case 'Secant'
                    [root, i, x_0] = Secant(f,app.currentIteration, x0, x1,maxItr,maxError,mode, app.UITable2);
                    app.X0EditField.Value = x_0;
                    app.X1EditField.Value = root;
                    app.currentIteration = i;
                    
                    cla(app.UIAxes);
                    fplot(app.UIAxes,f);
                    hold(app.UIAxes);
                                
                case 'Bierge Vieta'
                    [root, i] = BergeViete(f, app.currentIteration, x0, maxError, maxItr, mode, app.UITable2);
                    app.X0EditField.Value = root;
                    app.currentIteration = i;
                    
                    cla(app.UIAxes);
                    fplot (app.UIAxes,f);
                    hold(app.UIAxes);
                    
                case 'General Algorithm'
                    interval = app.IntervalEditField.Value;
                    [roots] = generalAlgorithm(f,x0,x1,maxError, interval);
                    app.generalRootsLabel.Text = strcat('Roots. ', mat2str(roots));
                    cla(app.UIAxes);
                    fplot(app.UIAxes,f);
            end
            
        end

        % Value changed function: FunctionIsEditField
        function FunctionIsEditFieldValueChanged(app, event)
            app.value = app.FunctionIsEditField.Value;
            app.X0EditField.Value = 0;
            app.currentIteration = 0;
            app.MaxIterationsEditField.Value = 50;
            
        end

        % Callback function
        function FunctionIsEditFieldValueChanging(app, event)
            changingValue = event.Value;
            x=app.FunctionIsEditField.Value;
            app.FunctionIsEditField.Value='x'; 
        end

        % Value changed function: SelectMethodDropDown
        function SelectMethodDropDownValueChanged(app, event)
            app.UITable2.Data = {};
            app.currentIteration = 0;
            val = app.SelectMethodDropDown.Value;
            if (val == "Fixed Point") || (val == "Newton-Raphson") || (val == "Bierge Vieta")
                app.X1EditField.Enable = 'off';
            else
                app.X1EditField.Enable = 'on';
                
            end
            
            if (val == "General Algorithm")
                app.IntervalEditField.Visible = 'on';
                app.IntervalEditFieldLabel.Visible = 'on';
                app.generalRootsLabel.Visible = 'on';
                app.UITable2.Enable = 'off';
            else
                app.IntervalEditField.Visible = 'off';
                app.IntervalEditFieldLabel.Visible = 'off';
                app.generalRootsLabel.Visible = 'off';
                app.UITable2.Enable = 'on';
            end
            
        end

        % Value changed function: X0EditField
        function X0EditFieldValueChanged(app, event)
            app.value = app.X0EditField.Value;
            app.currentIteration = 0;
            app.UITable2.Data = {};
        end

        % Button pushed function: selectfileButton
        function selectfileButtonPushed(app, event)
         [filename pathname] = uigetfile({'*.txt'},'File Selector');
         allpath =strcat(pathname,filename);
         fileID = fopen(allpath,'r');
         C = textscan(fileID,'%s %s %s','Delimiter',':');
         fclose(fileID);
         %myCell = C{1}(:,1);
        app.FunctionIsEditField.Value= C{1}{2};
         app.maxErrorEditField.Value=str2double(C{1}{4});
        app.MaxIterationsEditField.Value = str2double(C{1}{5});
        interval =  C{1}{3};
         interval =  erase(interval,']');
         interval =  erase(interval,'[');
         interval = strsplit(interval,' ');
         app.X0EditField.Value = str2double(interval(1));
         app.X1EditField.Value = str2double(interval(2));
         temp = str2double(C{1}{1});
          
          if temp == 1         
                    app.SelectMethodDropDown.Value = 'Bisection';
          elseif temp == 2 
                    app.SelectMethodDropDown.Value = 'False-Position';
          elseif temp == 3 
                    app.SelectMethodDropDown.Value = 'Fixed Point'; 
          elseif temp == 4 
                    app.SelectMethodDropDown.Value = 'Newton-Raphson';
          elseif temp == 5 
                    app.SelectMethodDropDown.Value = 'Secant';
          elseif temp == 6 
                    app.SelectMethodDropDown.Value = 'Bierge Vieta';
                    
          end
       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Panel = uipanel(app.HomeTab);
            app.SelectMethodDropDownLabel = uilabel(app.Panel);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.Panel);
            app.SelectMethodDropDown.Items = {'Bisection', 'False-Position', 'Fixed Point', 'Newton-Raphson', 'Secant', 'Bierge Vieta', 'General Algorithm'};
            app.SelectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMethodDropDownValueChanged, true);
            app.SelectMethodDropDown.Value = 'General Algorithm';
            app.FunctionIsEditFieldLabel = uilabel(app.Panel);
            app.FunctionIsEditFieldLabel.Text = 'Function Is';
            app.FunctionIsEditField = uieditfield(app.Panel, 'text');
            app.FunctionIsEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionIsEditFieldValueChanged, true);
            app.FunctionIsEditField.Value = 'exp(-x)-x';
            app.X0EditFieldLabel = uilabel(app.Panel);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.Panel, 'numeric');
            app.X0EditField.ValueChangedFcn = createCallbackFcn(app, @X0EditFieldValueChanged, true);
            app.X1EditFieldLabel = uilabel(app.Panel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.Panel, 'numeric');
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'ɛ';
            app.maxErrorEditField = uieditfield(app.Panel, 'numeric');
            app.maxErrorEditField.Value = 0.0001;
            app.MaxIterationsEditFieldLabel = uilabel(app.Panel);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.Panel, 'numeric');
            app.MaxIterationsEditField.Limits = [0 Inf];
            app.MaxIterationsEditField.Value = 50;
            app.ModeSwitchLabel = uilabel(app.Panel);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.Panel, 'slider');
            app.ModeSwitch.Items = {'Quick', 'Step'};
            app.ModeSwitch.Value = 'Quick';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.selectfileButton = uibutton(app.Panel, 'push');
            app.selectfileButton.ButtonPushedFcn = createCallbackFcn(app, @selectfileButtonPushed, true);
            app.selectfileButton.Text = 'select file';
            app.IntervalEditFieldLabel = uilabel(app.Panel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.Panel, 'numeric');
            app.IntervalEditField.Value = 0.05;
            app.generalRootsLabel = uilabel(app.Panel);
            app.generalRootsLabel.Text = 'Roots';
            app.UIAxes = uiaxes(app.HomeTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTickLabel = {''; '-50'; ''; '50'; ''};
            app.UITable2 = uitable(app.HomeTab);
            app.UITable2.ColumnName = {'Iteration'; 'X0'; 'X1'; 'Root'; 'Error'; 'Time'};
            app.UITable2.RowName = {};
            app.fileButton = uibutton(app.UIFigure, 'push');
            app.fileButton.Text = 'file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end