classdef gaussJordanGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GaussJordanUIFigure            matlab.ui.Figure
        GaussJordanSolverLabel         matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        XvaluesTextAreaLabel           matlab.ui.control.Label
        XvaluesTextArea                matlab.ui.control.TextArea
        EnteranequationEditFieldLabel  matlab.ui.control.Label
        EqnText                        matlab.ui.control.EditField
    end


    properties (Access = public)
        matrix = [[]]; % equations matrix (A|b)
        cnt = 1;
    end


    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            input = app.EqnText.Value;
            temp = strsplit(input);
            temp
            
            coeffs = [];
            for i = 1 : length(temp)
               coeffs(i) = str2num(cell2mat(temp(i)));
            end
            coeffs
            %for i = 1 : length(coeffs)
            %    app.matrix(len+1, i) = coeffs(i);
            %end
            app.matrix(app.cnt, :) = coeffs;
            app.cnt = app.cnt + 1;
            app.EqnText.Value = '';
            app.matrix
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            xs = GaussJordan(app.cnt-1, app.matrix);
            s = "";
            for i = 1 : length(xs)
                s = s + sprintf("X%d = %f\n", i,xs(i));
            end
            app.XvaluesTextArea.Value = s;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GaussJordanUIFigure = uifigure;
            app.GaussJordanUIFigure.Name = 'Gauss Jordan';
            app.GaussJordanSolverLabel = uilabel(app.GaussJordanUIFigure);
            app.GaussJordanSolverLabel.Text = 'Gauss Jordan Solver';
            app.CalculateButton = uibutton(app.GaussJordanUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AddButton = uibutton(app.GaussJordanUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.XvaluesTextAreaLabel = uilabel(app.GaussJordanUIFigure);
            app.XvaluesTextAreaLabel.Text = 'X values';
            app.XvaluesTextArea = uitextarea(app.GaussJordanUIFigure);
            app.EnteranequationEditFieldLabel = uilabel(app.GaussJordanUIFigure);
            app.EnteranequationEditFieldLabel.Text = 'Enter an equation';
            app.EqnText = uieditfield(app.GaussJordanUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gaussJordanGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GaussJordanUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GaussJordanUIFigure)
        end
    end
end