classdef interpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterpolationUIFigure      matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        HomeTab                    matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        SelectMethodDropDownLabel  matlab.ui.control.Label
        SelectMethodDropDown       matlab.ui.control.DropDown
        OrderEditFieldLabel        matlab.ui.control.Label
        OrderEditField             matlab.ui.control.EditField
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        CalculateButton            matlab.ui.control.Button
        selectfileButton           matlab.ui.control.Button
        EnterasamplepointLabel     matlab.ui.control.Label
        EnteraquerypointLabel      matlab.ui.control.Label
        XEditField_2Label          matlab.ui.control.Label
        XEditField_Query           matlab.ui.control.NumericEditField
        AddButton                  matlab.ui.control.Button
        AddButton_2                matlab.ui.control.Button
        CalculateButton_2          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        PolyText                   matlab.ui.control.TextArea
        ResultText                 matlab.ui.control.TextArea
    end


    properties (Access = public)
        xs = [];
        ys = [];
        order = 4;
        points = [];
    end


    methods (Access = private)

        % Value changed function: SelectMethodDropDown
        function SelectMethodDropDownValueChanged(app, event)
            value = app.SelectMethodDropDown.Value;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            app.xs(length(app.xs)+1) = x;
            app.ys(length(app.ys)+1) = y;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            zoom(app.UIAxes,'on');
            app.order = app.OrderEditField.Value;
            switch (app.SelectMethodDropDown.Value)
                case 'Newton'
                    [poly, result, time] = NewtonInterpolation(app.order, app.points, app.xs, app.ys);
                    spoly = sprintf("poly function: \n%s\n\n", char(poly));
                    spoly = spoly + sprintf('time: %f sec', time);
                    app.PolyText.Value = char(spoly);
                    sres = "";
                    for i=1 : length(app.points)
                        sres = sres + sprintf("(%d, %d)\n", app.points(i), result(i));
                    end
                    app.ResultText.Value = char(sprintf("query points:\n%s", sres));
                    
                    cla(app.UIAxes);
                    fplot(app.UIAxes,poly);
                    hold(app.UIAxes);
                case 'Lagrange'
                    [poly, result, time] = lagrange(app.order, app.points, app.xs, app.ys);
                    spoly = sprintf("poly function: \n%s\n\n", char(poly));
                    spoly = spoly + sprintf('time: %f sec', time);
                    app.PolyText.Value = char(spoly);
                    sres = "";
                    for i=1 : length(app.points)
                        sres = sres + sprintf("(%d, %d)\n", app.points(i), result(i));
                    end
                    app.ResultText.Value = char(sprintf("query points:\n%s", sres));
                    
                    cla(app.UIAxes);
                    fplot(app.UIAxes,poly);
                    hold(app.UIAxes);
            end
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            x = app.XEditField_Query.Value;
            min(app.xs)
            max(app.xs)
            x
            if((x >= min(app.xs)) && (x <= max(app.xs)))
                app.points(length(app.points)+1) = x;
            end
            app.points
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            zoom(app.UIAxes,'on');
            app.order = app.OrderEditField.Value;
            switch (app.SelectMethodDropDown.Value)
                case 'Newton'
                    [poly, result, time] = NewtonInterpolation(app.order, app.points, app.xs, app.ys);
                    spoly = sprintf("poly function: \n%s\n\n", char(poly));
                    spoly = spoly + sprintf('time: %f sec', time);
                    app.PolyText.Value = char(spoly);
                    sres = "";
                    for i=1 : length(app.points)
                        sres = sres + sprintf("(%d, %d)\n", app.points(i), result(i));
                    end
                    app.ResultText.Value = char(sprintf("query points:\n%s", sres));
                    
                    cla(app.UIAxes);
                    fplot(app.UIAxes,poly);
                    hold(app.UIAxes);
                case 'Lagrange'
                    [poly, result, time] = lagrange(app.order, app.points, app.xs, app.ys);
                    spoly = sprintf("poly function: \n%s\n\n", char(poly));
                    spoly = spoly + sprintf('time: %f sec', time);
                    app.PolyText.Value =char(spoly);
                    sres = "";
                    for i=1 : length(app.points)
                        sres = sres + sprintf("(%d, %d)\n", app.points(i), result(i));
                    end
                    app.ResultText.Value = char(sprintf("query points:\n%s", sres));
                    
                    cla(app.UIAxes);
                    fplot(app.UIAxes,poly);
                    hold(app.UIAxes);
            end
        end

        % Button pushed function: selectfileButton
        function selectfileButtonPushed(app, event)
                 [filename pathname] = uigetfile({'*.txt'},'File Selector');
             allpath =strcat(pathname,filename);
             fileID = fopen(allpath,'r');
             C = textscan(fileID,'%s %s %s','Delimiter',':');
             fclose(fileID);
             %myCell = C{1}(:,1);
            app.OrderEditField.Value = C{1}{2};
             xpoints =  C{1}{3};
             xpoints =  erase(xpoints,']');    
             allpath = strcat(pathname,filename);
             xpoints =  erase(xpoints,'[');
             xpoints = strsplit(xpoints,',');
             for i=1:(length(xpoints))
              app.xs(i) = str2double(xpoints(i));
             end
             ypoints =  C{1}{4};
             ypoints =  erase(ypoints,']');
             ypoints =  erase(ypoints,'[');
             ypoints = strsplit(ypoints,',');
             for i=1:(length(ypoints))
              app.ys(i) = str2double(ypoints(i));
             end 
     
             qpoints =  C{1}{5};
             qpoints =  erase(qpoints,']');
             qpoints =  erase(qpoints,'[');
             qpoints = strsplit(qpoints,',');
             for i=1:(length(qpoints))
              app.points(i) = str2double(qpoints(i));
             end 


             temp = str2double(C{1}{1});
     
              if temp == 1         
                        app.SelectMethodDropDown.Value = 'Newton';
              elseif temp == 2 
                       app.SelectMethodDropDown.Value = 'Lagrange';
     
              end
              
              app.order
              app.points
              app.xs
              app.ys
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterpolationUIFigure = uifigure;
            app.InterpolationUIFigure.Name = 'Interpolation';
            app.TabGroup = uitabgroup(app.InterpolationUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Panel = uipanel(app.HomeTab);
            app.SelectMethodDropDownLabel = uilabel(app.Panel);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.Panel);
            app.SelectMethodDropDown.Items = {'Newton', 'Lagrange'};
            app.SelectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMethodDropDownValueChanged, true);
            app.SelectMethodDropDown.Value = 'Newton';
            app.OrderEditFieldLabel = uilabel(app.Panel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.Panel, 'text');
            app.OrderEditField.Value = '4';
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel, 'numeric');
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.selectfileButton = uibutton(app.Panel, 'push');
            app.selectfileButton.ButtonPushedFcn = createCallbackFcn(app, @selectfileButtonPushed, true);
            app.selectfileButton.Text = 'select file';
            app.EnterasamplepointLabel = uilabel(app.Panel);
            app.EnterasamplepointLabel.Text = 'Enter a sample point';
            app.EnteraquerypointLabel = uilabel(app.Panel);
            app.EnteraquerypointLabel.Text = 'Enter a query point';
            app.XEditField_2Label = uilabel(app.Panel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_Query = uieditfield(app.Panel, 'numeric');
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddButton_2 = uibutton(app.Panel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.CalculateButton_2 = uibutton(app.Panel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.UIAxes = uiaxes(app.HomeTab);
            title(app.UIAxes, 'polynomial plotting')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLim = [0 1];
            app.UIAxes.CLimMode = 'manual';
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-100 -50 0 50 100];
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-100 -50 0 50 100];
            app.UIAxes.YTickLabel = {''; '-50'; ''; '50'; ''};
            app.PolyText = uitextarea(app.HomeTab);
            app.PolyText.Value = {'Polynomial function:'; 'Time:'};
            app.ResultText = uitextarea(app.HomeTab);
            app.ResultText.Value = {'Query point:'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = interpolation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterpolationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterpolationUIFigure)
        end
    end
end